classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ThegapfunctioneVButtonGroup     matlab.ui.container.ButtonGroup
        customfunctionButton_2          matlab.ui.control.RadioButton
        model1Button_2                  matlab.ui.control.RadioButton
        model2Button_2                  matlab.ui.control.RadioButton
        ThetightbindingmodeleVButtonGroup  matlab.ui.container.ButtonGroup
        customfunctionButton            matlab.ui.control.RadioButton
        model1Button                    matlab.ui.control.RadioButton
        model2Button                    matlab.ui.control.RadioButton
        ThenumberofbandButtonGroup      matlab.ui.container.ButtonGroup
        onebandButton                   matlab.ui.control.RadioButton
        twobandsButton                  matlab.ui.control.RadioButton
        customfunctionTextArea          matlab.ui.control.TextArea
        customfunctionLabel             matlab.ui.control.Label
        gapfunctionTextArea             matlab.ui.control.TextArea
        gapfunctionTextAreaLabel        matlab.ui.control.Label
        U_intraeVTextArea               matlab.ui.control.TextArea
        U_intraeVTextAreaLabel          matlab.ui.control.Label
        U_intereVTextArea               matlab.ui.control.TextArea
        U_intereVTextAreaLabel          matlab.ui.control.Label
        n_qTextAreaLabel                matlab.ui.control.Label
        n_qTextArea                     matlab.ui.control.TextArea
        EeVTextAreaLabel                matlab.ui.control.Label
        EeVTextArea                     matlab.ui.control.TextArea
        n_ETextAreaLabel                matlab.ui.control.Label
        n_ETextArea                     matlab.ui.control.TextArea
        deVTextAreaLabel                matlab.ui.control.Label
        deVTextArea                     matlab.ui.control.TextArea
        ThewaytocalculateButtonGroup    matlab.ui.container.ButtonGroup
        TmatrixButton                   matlab.ui.control.RadioButton
        JDOSButton                      matlab.ui.control.RadioButton
        calculateButton                 matlab.ui.control.Button
        customfunction2Label            matlab.ui.control.Label
        customfunction2TextArea         matlab.ui.control.TextArea
        model1fortheonebandmodelPanel   matlab.ui.container.Panel
        coskxTextArea_4Label            matlab.ui.control.Label
        coskxTextArea_4                 matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        coskyLabel                      matlab.ui.control.Label
        coskyTextArea                   matlab.ui.control.TextArea
        model2fortheonebandmodelPanel   matlab.ui.container.Panel
        coskxcoskyLabel                 matlab.ui.control.Label
        coskxcoskyTextArea              matlab.ui.control.TextArea
        model1forthetwobandsmodelPanel  matlab.ui.container.Panel
        coskxTextArea_2Label            matlab.ui.control.Label
        coskxTextArea_2                 matlab.ui.control.TextArea
        Label_2                         matlab.ui.control.Label
        coskyTextArea_2Label            matlab.ui.control.Label
        coskyTextArea_2                 matlab.ui.control.TextArea
        thefirstbandLabel               matlab.ui.control.Label
        thesecondbandLabel              matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Label_4                         matlab.ui.control.Label
        coskxTextArea_3Label            matlab.ui.control.Label
        coskxTextArea_3                 matlab.ui.control.TextArea
        coskyTextArea_3Label            matlab.ui.control.Label
        coskyTextArea_3                 matlab.ui.control.TextArea
        TextArea_2Label                 matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        model2forthetwobandsmodelPanel  matlab.ui.container.Panel
        thefirstbandLabel_2             matlab.ui.control.Label
        thesecondbandLabel_2            matlab.ui.control.Label
        coskxcoskyTextArea_2Label       matlab.ui.control.Label
        coskxcoskyTextArea_2            matlab.ui.control.TextArea
        coskxcoskyTextArea_3Label       matlab.ui.control.Label
        coskxcoskyTextArea_3            matlab.ui.control.TextArea
        model1forthegapfunctionPanel    matlab.ui.container.Panel
        coskxcoskyTextArea_4Label       matlab.ui.control.Label
        coskxcoskyTextArea_4            matlab.ui.control.TextArea
        model2forthegapfunctionPanel    matlab.ui.container.Panel
        sinkxisinkyTextAreaLabel        matlab.ui.control.Label
        sinkxisinkyTextArea             matlab.ui.control.TextArea
        checkButton                     matlab.ui.control.Button
        gapfunction2TextAreaLabel       matlab.ui.control.Label
        gapfunction2TextArea            matlab.ui.control.TextArea
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            % Read the data entered by the user:
            selectedButton1 = app.ThenumberofbandButtonGroup.SelectedObject;
            selectedButton2 = app.ThewaytocalculateButtonGroup.SelectedObject;
            selectedButton3 = app.ThetightbindingmodeleVButtonGroup.SelectedObject;
            f1 = app.customfunctionTextArea.Value;
            selectedButton4 = app.ThegapfunctioneVButtonGroup.SelectedObject;
            f2 = app.gapfunctionTextArea.Value;
            uintra = str2double(app.U_intraeVTextArea.Value);
            uinter = str2double(app.U_intereVTextArea.Value);
            e = str2double(app.EeVTextArea.Value);
            nq = fix(str2double(app.n_qTextArea.Value));
            ne = fix(str2double(app.n_ETextArea.Value));
            d1 = str2double(app.deVTextArea.Value);
            switch selectedButton1.Text
                case 'one band'
                    % calculating the BQPI pattern for one band mode
                    n_q = nq;  % number of points in k-space
                    E = e;  % the energy range (-E,E)
                    n_E = ne;  % the number of energy layers
                    [kx,ky] = meshgrid(linspace(-pi,pi,n_q));  % use the ,linspace  command to generate a coordinate vector, and then use the ‘meshgrid’ command to generate a matrix
                    Epoints = linspace(-E,E,n_E);  % the energy range (-E,E) is divided into several points
                    I = eye(2);  % define a 2 by 2 identity matrix
                    U0 = uintra;  % The scattering potential
                    U = [U0,0;0,-U0];  % generate the scattering potential matrix U
                    d = d1;  % energy smearing δ in Greenes function
                    
                    switch selectedButton3.Text
                        % choose for the tight binding model
                        case 'custom function'
                            E_tb = eval(f1{1});
                        case 'model 1'
                            parameter1 = str2double(app.coskxTextArea_4.Value);
                            parameter2 = str2double(app.coskyTextArea.Value);
                            E_tb = parameter1*cos(kx)+parameter2*cos(ky);
                        case 'model 2'
                            parameter1 = str2double(app.coskxcoskyTextArea.Value);
                            E_tb = parameter1*cos(kx).*cos(ky);
                    end
                    
                    switch selectedButton4.Text
                        % choose for the tight binding model( only for the two bands model)
                        case 'custom function'
                             D = eval(f2{1});
                        case 'model 1'
                            parameter1 = str2double(app.coskxcoskyTextArea_4.Value);
                            D = parameter1*(cos(kx)-cos(ky));
                        case 'model 2'
                            parameter1 = str2double(app.sinkxisinkyTextArea.Value);
                            D = parameter1*(sin(kx)+1i*sin(ky));
                    end
                    % Initialize each element of the matrices G_0 (k,E) and G_0 (r,E)
                    G11 = zeros(n_q,n_q,n_E);
                    G12 = zeros(n_q,n_q,n_E);
                    G21 = zeros(n_q,n_q,n_E);
                    G22 = zeros(n_q,n_q,n_E);
                    G11r = zeros(n_q,n_q,n_E);
                    G12r = zeros(n_q,n_q,n_E);
                    G21r = zeros(n_q,n_q,n_E);
                    G22r= zeros(n_q,n_q,n_E);
                    for k=1:n_E
                    for i=1:n_q
                    for j=1:n_q
                       H = [E_tb(i,j),D(i,j);conj(D(i,j)),-E_tb(i,j)];  % calculate H_0 (k)
                       G0 = I/((Epoints(k) + 1i*d)*I - H);  % calculate G_0 (k,E)
                       % write each element of G_0 (k,E) (2×2 matrix) separately:
                       G11(i,j,k) = G0(1,1);
                       G12(i,j,k) = G0(1,2);
                       G21(i,j,k) = G0(2,1);
                       G22(i,j,k) = G0(2,2);
                    end
                    end
                    % transform the k-space Green's function into a real-space Green's function:
                       G11r(:,:,k) = fftshift(ifft2(G11(:,:,k)))/(n_q*n_q);
                       G12r(:,:,k) = fftshift(ifft2(G12(:,:,k)))/(n_q*n_q);
                       G21r(:,:,k) = fftshift(ifft2(G21(:,:,k)))/(n_q*n_q);
                       G22r(:,:,k) = fftshift(ifft2(G22(:,:,k)))/(n_q*n_q);
                    end
                    % calculate and plot the spectral density function:
                    Ak = -imag(G11 + G22)/pi;
                    figure('name','the spectral density');
                    colorscale = 'gray';
                    for k=1:n_E
                        subplot(ceil(n_E/4),4,k);
                        imagesc(abs(Ak(:,:,k)));
                        colormap(colorscale);
                        axis equal
                        xticks([1 n_q]);
                        xticklabels({'-\pi','\pi'});
                        yticks([1 n_q]);
                        yticklabels({'-\pi','\pi'});
                        xlabel('kx');
                        ylabel('ky');
                        title([num2str(-E+(k-1)*2*E/(n_E-1)) 'eV']);
                    end
                    switch selectedButton2.Text
                        case 'T matrix'
                            % using T matrix method to calculate BQPI
                            % initialize each element of T(E)
                            T11 = zeros(1,n_E);
                            T12 = zeros(1,n_E);
                            T21 = zeros(1,n_E);
                            T22 = zeros(1,n_E);
                            %find the index number corresponding to r=0
                            sumG_ind = (n_q+1)/2;
                            %for each energy, use G_0 (0,E) to calculate the T-matrix
                            for k=1:n_E
                                A = U;
                                B = [G11r(sumG_ind,sumG_ind,k),G12r(sumG_ind,sumG_ind,k);...
                                    G21r(sumG_ind,sumG_ind,k),G22r(sumG_ind,sumG_ind,k)];
                                C = I - A*B;
                                T = I/C*A;
                                T11(k) = T(1,1);
                                T12(k) = T(1,2);
                                T21(k) = T(2,1);
                                T22(k) = T(2,2);
                            end
                            % calculate the BQPI pattern:
                            A11r = zeros(n_q,n_q,n_E);
                            A22r = zeros(n_q,n_q,n_E);
                            for k=1:n_E
                                fprintf('Have finished %8.5f%%\n',100/n_E*k)
                            for i=1:n_q
                            for j=1:n_q
                                G1r = [G11r(i,j,k),G12r(i,j,k);...
                                G21r(i,j,k),G22r(i,j,k)];
                                G2r = [G11r(n_q-i+1,n_q-j+1,k),G12r(n_q-i+1,n_q-j+1,k);...
                                G21r(n_q-i+1,n_q-j+1,k),G22r(n_q-i+1,n_q-j+1,k)];
                                T = [T11(k),T12(k);...
                                T21(k),T22(k)];
                                A = G2r*(T*G1r);
                                A11r(i,j,k) = A(1,1);
                                A22r(i,j,k) = A(2,2);
                            end
                            end
                            end
                            % calculate the δN(q,E):
                            dnr = zeros(n_q-1,n_q-1,n_E);
                            dnq = zeros(n_q-1,n_q-1,n_E);
                            for k=1:n_E
                                dnq(:,:,k) = -imag(fftshift(fft2(A11r(1:n_q-1,1:n_q-1,k))))/pi;
                                dnr(:,:,k) = ifft2(ifftshift(dnq(:,:,k)));
                            end
                            % plot pictures for dnr and dnq:
                            figure('name','dnr');
                            colorscale = 'gray';
                            for k=1:n_E
                                subplot(ceil(n_E/4),4,k);
                                imagesc(abs(dnr(:,:,k)));
                                colormap(colorscale);
                                axis equal
                                set(gca,'xtick',[],'xticklabel',[])
                                set(gca,'ytick',[],'yticklabel',[])
                                xlabel('x');
                                ylabel('y');
                                title([num2str(-E+(k-1)*2*E/(n_E-1)) 'eV']);
                            end
                            figure('name','dnq');
                            colorscale = 'gray';
                            for k=1:n_E
                                subplot(ceil(n_E/4),4,k);
                                imagesc(dnq(:,:,k));
                                colormap(colorscale);
                                axis equal
                                xticks([1 n_q-1]);
                                xticklabels({'-\pi','0','\pi'});
                                yticks([1 n_q-1]);
                                yticklabels({'-\pi','0','\pi'});
                                xlabel('qx');
                                ylabel('qy');
                                title([num2str(-E+(k-1)*2*E/(n_E-1)) 'eV']);
                            end
                        case 'JDOS'
                            % using J-DOS method to calculate BQPI
                            dnq1 = zeros(n_q,n_q,n_E);
                            for k=1:n_E
                                fprintf('Have finished %8.5f%%\n',100/n_E*k)
                            for m=1:n_q
                            for l=1:n_q
                                sum1 = 0;
                            for i=1:n_q
                            for j=1:n_q
                                ii = i + m;
                                jj = j + l;
                                if i + m > n_q
                                    ii = i + m - n_q ;
                                end
                                if j + l > n_q
                                    jj = j + l - n_q ;
                                end
                                if i + m < 1
                                    ii = i + m + n_q ;
                                end
                                if j + l < 1
                                    jj = j + l + n_q ;
                                end
                                sum1 = sum1 + Ak(i,j,k)*Ak(ii,jj,k);
                            end
                            end
                                dnq1(m,l,k) = sum1;
                            end
                            end
                            end
                            dnq=zeros(n_q,n_q,n_E);
                            for k=1:n_E
                                dnq(:,:,k)=fftshift(dnq1(:,:,k));
                            end
                            figure('name','dnq');
                            colorscale = 'gray';
                            for k=1:n_E
                                dnq_tem=zeros(3*n_q,3*n_q);
                                dnq_tem(1:n_q,1:n_q)=abs(dnq(:,:,k));
                                dnq_tem(1:n_q,n_q+1:2*n_q)=abs(dnq(:,:,k));
                                dnq_tem(1:n_q,2*n_q+1:3*n_q)=abs(dnq(:,:,k));
                                dnq_tem(n_q+1:2*n_q,1:n_q)=abs(dnq(:,:,k));
                                dnq_tem(n_q+1:2*n_q,n_q+1:2*n_q)=abs(dnq(:,:,k));
                                dnq_tem(n_q+1:2*n_q,2*n_q+1:3*n_q)=abs(dnq(:,:,k));
                                dnq_tem(2*n_q+1:3*n_q,1:n_q)=abs(dnq(:,:,k));
                                dnq_tem(2*n_q+1:3*n_q,n_q+1:2*n_q)=abs(dnq(:,:,k));
                                dnq_tem(2*n_q+1:3*n_q,2*n_q+1:3*n_q)=abs(dnq(:,:,k));
                                dnq1=dnq_tem((n_q+1)/2:(n_q+1)/2+2*n_q,(n_q+1)/2:(n_q+1)/2+2*n_q);
                                subplot(ceil(n_E/4),4,k);
                                imagesc(dnq1);
                                colormap(colorscale);
                                axis equal
                                xticks([1 n_q 2*n_q]);
                                xticklabels({'-2\pi','0','2\pi'});
                                yticks([1 n_q 2*n_q]);
                                yticklabels({'-2\pi','0','2\pi'});
                                xlabel('qx');
                                ylabel('qy');
                                title([num2str(-E+(k-1)*2*E/(n_E-1)) 'eV']);
                            end
                    end
                case 'two bands'
                    % calculate BQPI pattern for two bands mode
                    f3 = app.customfunction2TextArea.Value;
                    f4 = app.gapfunction2TextArea.Value;
                    n_q = nq;
                    E = e;
                    n_E = ne;
                    [kx,ky] = meshgrid(linspace(-pi,pi,n_q));
                    Epoints = linspace(-E,E,n_E);
                    I = eye(4);
                    switch selectedButton3.Text
                        case 'custom function'
                            epsilon_alpha = eval(f1{1});
                            epsilon_beta = eval(f3{1});
                        case 'model 1'
                            parameter1 = str2double(app.coskxTextArea_2);
                            parameter2 = str2double(app.coskyTextArea_2);
                            parameter3 = str2double(app.TextArea);
                            parameter4 = str2double(app.coskxTextArea_3);
                            parameter5 = str2double(app.coskyTextArea_3);
                            parameter6 = str2double(app.TextArea_2);
                            epsilon_alpha = parameter1*cos(kx)+parameter2*cos(ky)+parameter3;
                            epsilon_beta = parameter4*cos(kx)+parameter5*cos(ky)+parameter6;
                        case 'medel 2'
                            parameter1 = str2double(app.coskxcoskyTextArea_2);
                            parameter2 = str2double(app.coskxcoskyTextArea_3);
                            epsilon_alpha =  parameter1*cos(kx).*cos(ky);
                            epsilon_beta = parameter2*cos(kx).*cos(ky);
                    end
                    switch selectedButton4.Text
                        case 'custom function'
                             D1 = eval(f2{1});
                             D2 = eval(f4{1});
%                         case 'model 1'
%                             parameter1 = str2double(app.coskxcoskyTextArea_4.Value);
%                             D = parameter1*(cos(kx)-cos(ky));
%                         case 'model 2'
%                             parameter1 = str2double(app.sinkxisinkyTextArea.Value);
%                             D = parameter1*(sin(kx)+1i*sin(ky));
                    end
                    U_intra = uintra;
                    U_inter = uinter;
                    U = [U_intra,0,U_inter,0;...
                        0,-U_intra,0,-U_inter;...
                        U_inter,0,U_intra,0;...
                        0,-U_inter,0,-U_intra];
                    d = d1;
                    
                    G11 = zeros(n_q,n_q,n_E);
                    G12 = zeros(n_q,n_q,n_E);
                    G13 = zeros(n_q,n_q,n_E);
                    G14 = zeros(n_q,n_q,n_E);
                    G21 = zeros(n_q,n_q,n_E);
                    G22 = zeros(n_q,n_q,n_E);
                    G23 = zeros(n_q,n_q,n_E);
                    G24 = zeros(n_q,n_q,n_E);
                    G31 = zeros(n_q,n_q,n_E);
                    G32 = zeros(n_q,n_q,n_E);
                    G33 = zeros(n_q,n_q,n_E);
                    G34 = zeros(n_q,n_q,n_E);
                    G41 = zeros(n_q,n_q,n_E);
                    G42 = zeros(n_q,n_q,n_E);
                    G43 = zeros(n_q,n_q,n_E);
                    G44 = zeros(n_q,n_q,n_E);
                    G11r = zeros(n_q,n_q,n_E);
                    G12r = zeros(n_q,n_q,n_E);
                    G13r = zeros(n_q,n_q,n_E);
                    G14r = zeros(n_q,n_q,n_E);
                    G21r = zeros(n_q,n_q,n_E);
                    G22r = zeros(n_q,n_q,n_E);
                    G23r = zeros(n_q,n_q,n_E);
                    G24r = zeros(n_q,n_q,n_E);
                    G31r = zeros(n_q,n_q,n_E);
                    G32r = zeros(n_q,n_q,n_E);
                    G33r = zeros(n_q,n_q,n_E);
                    G34r = zeros(n_q,n_q,n_E);
                    G41r = zeros(n_q,n_q,n_E);
                    G42r = zeros(n_q,n_q,n_E);
                    G43r = zeros(n_q,n_q,n_E);
                    G44r = zeros(n_q,n_q,n_E);
                    for k=1:n_E
                    for i=1:n_q
                    for j=1:n_q
                        H = [epsilon_alpha(i,j),D1(i,j),0,0;...
                            conj(D1(i,j)),-epsilon_alpha(i,j),0,0;...
                            0,0,epsilon_beta(i,j),D2(i,j);...
                            0,0,conj(D2(i,j)),-epsilon_beta(i,j)];
                        G0 = I/((Epoints(k) + 1i*d)*I - H);
                        G11(i,j,k) = G0(1,1);
                        G12(i,j,k) = G0(1,2);
                        G13(i,j,k) = G0(1,3);
                        G14(i,j,k) = G0(1,4);
                        G21(i,j,k) = G0(2,1);
                        G22(i,j,k) = G0(2,2);
                        G23(i,j,k) = G0(2,3);
                        G24(i,j,k) = G0(2,4);
                        G31(i,j,k) = G0(3,1);
                        G32(i,j,k) = G0(3,2);
                        G33(i,j,k) = G0(3,3);
                        G34(i,j,k) = G0(3,4);
                        G41(i,j,k) = G0(4,1);
                        G42(i,j,k) = G0(4,2);
                        G43(i,j,k) = G0(4,3);
                        G44(i,j,k) = G0(4,4);
                    end
                    end
                        G11r(:,:,k) = fftshift(ifft2(G11(:,:,k)))/(n_q*n_q);
                        G12r(:,:,k) = fftshift(ifft2(G12(:,:,k)))/(n_q*n_q);
                        G13r(:,:,k) = fftshift(ifft2(G13(:,:,k)))/(n_q*n_q);
                        G14r(:,:,k) = fftshift(ifft2(G14(:,:,k)))/(n_q*n_q);
                        G21r(:,:,k) = fftshift(ifft2(G21(:,:,k)))/(n_q*n_q);
                        G22r(:,:,k) = fftshift(ifft2(G22(:,:,k)))/(n_q*n_q);
                        G23r(:,:,k) = fftshift(ifft2(G23(:,:,k)))/(n_q*n_q);
                        G24r(:,:,k) = fftshift(ifft2(G24(:,:,k)))/(n_q*n_q);
                        G31r(:,:,k) = fftshift(ifft2(G31(:,:,k)))/(n_q*n_q);
                        G32r(:,:,k) = fftshift(ifft2(G32(:,:,k)))/(n_q*n_q);
                        G33r(:,:,k) = fftshift(ifft2(G33(:,:,k)))/(n_q*n_q);
                        G34r(:,:,k) = fftshift(ifft2(G34(:,:,k)))/(n_q*n_q);
                        G41r(:,:,k) = fftshift(ifft2(G41(:,:,k)))/(n_q*n_q);
                        G42r(:,:,k) = fftshift(ifft2(G42(:,:,k)))/(n_q*n_q);
                        G43r(:,:,k) = fftshift(ifft2(G43(:,:,k)))/(n_q*n_q);
                        G44r(:,:,k) = fftshift(ifft2(G44(:,:,k)))/(n_q*n_q);
                    end
                    Ak = -imag(G11+G22+G33+G44)/pi;
                    figure('name','the spectral density');
                    colorscale = 'gray';
                    for k=1:n_E
                        subplot(ceil(n_E/4),4,k);
                        imagesc(abs(Ak(:,:,k)));
                        colormap(colorscale);
                        axis equal
                        xticks([1 n_q]);
                        xticklabels({'-\pi','\pi'});
                        yticks([1 n_q]);
                        yticklabels({'-\pi','\pi'});
                        xlabel('kx');
                        ylabel('ky');
                        title([num2str(-E+(k-1)*2*E/(n_E-1)) 'eV']);
                    end
                    switch selectedButton2.Text
                        case 'T matrix'
                            T11 = zeros(1,n_E);
                            T12 = zeros(1,n_E);
                            T13 = zeros(1,n_E);
                            T14 = zeros(1,n_E);
                            T21 = zeros(1,n_E);
                            T22 = zeros(1,n_E);
                            T23 = zeros(1,n_E);
                            T24 = zeros(1,n_E);
                            T31 = zeros(1,n_E);
                            T32 = zeros(1,n_E);
                            T33 = zeros(1,n_E);
                            T34 = zeros(1,n_E);
                            T41 = zeros(1,n_E);
                            T42 = zeros(1,n_E);
                            T43 = zeros(1,n_E);
                            T44 = zeros(1,n_E);
                            sumG_ind = (n_q+1)/2;
                            for k=1:n_E
                                A = U;
                                B = [G11r(sumG_ind,sumG_ind,k),G12r(sumG_ind,sumG_ind,k),...
                                    G13r(sumG_ind,sumG_ind,k),G14r(sumG_ind,sumG_ind,k);...
                                    G21r(sumG_ind,sumG_ind,k),G22r(sumG_ind,sumG_ind,k),...
                                    G23r(sumG_ind,sumG_ind,k),G24r(sumG_ind,sumG_ind,k);...
                                    G31r(sumG_ind,sumG_ind,k),G32r(sumG_ind,sumG_ind,k),...
                                    G33r(sumG_ind,sumG_ind,k),G34r(sumG_ind,sumG_ind,k);...
                                    G41r(sumG_ind,sumG_ind,k),G42r(sumG_ind,sumG_ind,k),...
                                    G43r(sumG_ind,sumG_ind,k),G44r(sumG_ind,sumG_ind,k)];
                                C = I - A*B;
                                T = I/C*A;
                                T11(k) = T(1,1);
                                T12(k) = T(1,2);
                                T13(k) = T(1,3);
                                T14(k) = T(1,4);
                                T21(k) = T(2,1);
                                T22(k) = T(2,2);
                                T23(k) = T(2,3);
                                T24(k) = T(2,4);
                                T31(k) = T(3,1);
                                T32(k) = T(3,2);
                                T33(k) = T(3,3);
                                T34(k) = T(3,4); 
                                T41(k) = T(4,1);
                                T42(k) = T(4,2);
                                T43(k) = T(4,3);
                                T44(k) = T(4,4); 
                            end
                            
                            A11r = zeros(n_q,n_q,n_E);
                            A22r = zeros(n_q,n_q,n_E);
                            A33r = zeros(n_q,n_q,n_E);
                            A44r = zeros(n_q,n_q,n_E);
                            for k=1:n_E
                                fprintf('Have finished %8.5f%%\n',100/n_E*k)
                            for i=1:n_q
                            for j=1:n_q
                                G1 = [G11r(i,j,k),G12r(i,j,k),G13r(i,j,k),G14r(i,j,k);...
                                      G21r(i,j,k),G22r(i,j,k),G23r(i,j,k),G24r(i,j,k);...
                                      G31r(i,j,k),G32r(i,j,k),G33r(i,j,k),G34r(i,j,k);...
                                      G41r(i,j,k),G42r(i,j,k),G43r(i,j,k),G44r(i,j,k)];
                                G2 = [G11r(n_q-i+1,n_q-j+1,k),G12r(n_q-i+1,n_q-j+1,k),...
                                      G13r(n_q-i+1,n_q-j+1,k),G14r(n_q-i+1,n_q-j+1,k);...
                                      G21r(n_q-i+1,n_q-j+1,k),G22r(n_q-i+1,n_q-j+1,k),...
                                      G23r(n_q-i+1,n_q-j+1,k),G24r(n_q-i+1,n_q-j+1,k);...
                                      G31r(n_q-i+1,n_q-j+1,k),G32r(n_q-i+1,n_q-j+1,k),...
                                      G33r(n_q-i+1,n_q-j+1,k),G34r(n_q-i+1,n_q-j+1,k);...
                                      G41r(n_q-i+1,n_q-j+1,k),G42r(n_q-i+1,n_q-j+1,k),...
                                      G43r(n_q-i+1,n_q-j+1,k),G44r(n_q-i+1,n_q-j+1,k)];
                                T = [T11(k),T12(k),T13(k),T14(k);...
                                     T21(k),T22(k),T23(k),T24(k);...
                                     T31(k),T32(k),T33(k),T34(k);...
                                     T41(k),T42(k),T43(k),T44(k)];
                                A = G2*(T*G1);
                                A11r(i,j,k) = A(1,1);
                                A22r(i,j,k) = A(2,2);
                                A33r(i,j,k) = A(3,3);
                                A44r(i,j,k) = A(4,4);
                            end
                            end
                            end
                            dnr = zeros(n_q-1,n_q-1,n_E);
                            dnq = zeros(n_q-1,n_q-1,n_E);
                            for k=1:n_E
                                dnq(:,:,k) = -imag(fftshift(fft2(A11r(1:n_q-1,1:n_q-1,k)+A33r(1:n_q-1,1:n_q-1,k))))/pi;
                                dnr(:,:,k) = ifftshift(ifft2(dnq(:,:,k)));

                            end
                            figure('name','dnr');
                            colorscale = 'gray';
                            for k=1:n_E
                                subplot(ceil(n_E/4),4,k);
                                imagesc(abs(dnr(:,:,k)));
                                colormap(colorscale);
                                axis equal
                                set(gca,'xtick',[],'xticklabel',[])
                                set(gca,'ytick',[],'yticklabel',[])
                                xlabel('x');
                                ylabel('y');
                                title([num2str(-E+(k-1)*2*E/(n_E-1)) 'eV']);
                            end
                            figure('name','dnq');
                            colorscale = 'gray';
                            for k=1:n_E
                                subplot(ceil(n_E/4),4,k);
                                imagesc(dnq(:,:,k));
                                colormap(colorscale);
                                axis equal
                                xticks([1 n_q-1]);
                                xticklabels({'-\pi','0','\pi'});
                                yticks([1 n_q-1]);
                                yticklabels({'-\pi','0','\pi'});
                                xlabel('qx');
                                ylabel('qy');
                                title([num2str(-E+(k-1)*2*E/(n_E-1)) 'eV']);
                            end
                        case 'JDOS'
                            dnq1 = zeros(n_q,n_q,n_E);
                            for k=1:n_E
                                fprintf('Have finished %8.5f%%\n',100/n_E*k)
                            for m=1:n_q
                            for l=1:n_q
                                sum1 = 0;
                            for i=1:n_q
                            for j=1:n_q
                                ii = i + m;
                                jj = j + l;
                                if i + m > n_q
                                    ii = i + m - n_q ;
                                end
                                if j + l > n_q
                                    jj = j + l - n_q ;
                                end
                                if i + m < 1
                                    ii = i + m + n_q ;
                                end
                                if j + l < 1
                                    jj = j + l + n_q ;
                                end
                                sum1 = sum1 + Ak(i,j,k)*Ak(ii,jj,k);
                            end
                            end
                                dnq1(m,l,k) = sum1;
                            end
                            end
                            end
                            dnq=zeros(n_q,n_q,n_E);
                            for k=1:n_E
                                dnq(:,:,k)=fftshift(dnq1(:,:,k));
                            end
                    
                            figure('name','dnq');
                            colorscale = 'gray';
                            for k=1:n_E
                                dnq_tem=zeros(3*n_q,3*n_q);
                                dnq_tem(1:n_q,1:n_q)=abs(dnq(:,:,k));
                                dnq_tem(1:n_q,n_q+1:2*n_q)=abs(dnq(:,:,k));
                                dnq_tem(1:n_q,2*n_q+1:3*n_q)=abs(dnq(:,:,k));
                                dnq_tem(n_q+1:2*n_q,1:n_q)=abs(dnq(:,:,k));
                                dnq_tem(n_q+1:2*n_q,n_q+1:2*n_q)=abs(dnq(:,:,k));
                                dnq_tem(n_q+1:2*n_q,2*n_q+1:3*n_q)=abs(dnq(:,:,k));
                                dnq_tem(2*n_q+1:3*n_q,1:n_q)=abs(dnq(:,:,k));
                                dnq_tem(2*n_q+1:3*n_q,n_q+1:2*n_q)=abs(dnq(:,:,k));
                                dnq_tem(2*n_q+1:3*n_q,2*n_q+1:3*n_q)=abs(dnq(:,:,k));
                                dnq1=dnq_tem((n_q+1)/2:(n_q+1)/2+2*n_q,(n_q+1)/2:(n_q+1)/2+2*n_q);
                                subplot(ceil(n_E/4),4,k);
                                imagesc(dnq1);
                                colormap(colorscale);
                                axis equal
                                xticks([1 n_q 2*n_q]);
                                xticklabels({'-2\pi','0','2\pi'});
                                yticks([1 n_q 2*n_q]);
                                yticklabels({'-2\pi','0','2\pi'});
                                xlabel('qx');
                                ylabel('qy');
                                title([num2str(-E+(k-1)*2*E/(n_E-1)) 'eV']);
                            end
                    end
            end
        end

        % Selection changed function: ThenumberofbandButtonGroup
        function ThenumberofbandButtonGroupSelectionChanged(app, event)
            selectedButton = app.ThenumberofbandButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'one band'
                    set(app.U_intereVTextArea,'Visible','off');
                    set(app.U_intereVTextAreaLabel,'Visible','off');
                    set(app.model1Button_2,'Visible','on');
                    set(app.model2Button_2,'Visible','on');
                    set(app.gapfunction2TextArea,'Visible','off');
                    set(app.gapfunction2TextAreaLabel,'Visible','off');
                case 'two bands'
                    set(app.U_intereVTextArea,'Visible','on');
                    set(app.U_intereVTextAreaLabel,'Visible','on');
                    set(app.model1Button_2,'Visible','off');
                    set(app.model2Button_2,'Visible','off');
                    set(app.gapfunction2TextArea,'Visible','on');
                    set(app.gapfunction2TextAreaLabel,'Visible','on');

            end
        end

        % Selection changed function: 
        % ThetightbindingmodeleVButtonGroup
        function ThetightbindingmodeleVButtonGroupSelectionChanged(app, event)
            selectedButton1 = app.ThenumberofbandButtonGroup.SelectedObject;
            selectedButton2 = app.ThetightbindingmodeleVButtonGroup.SelectedObject;
            switch selectedButton2.Text
                case 'custom function'
                    switch selectedButton1.Text
                        case 'one band'
                            set(app.customfunctionTextArea,'Visible','on');
                            set(app.customfunctionLabel,'Visible','on');
                            set(app.customfunction2TextArea,'Visible','off');
                            set(app.customfunction2Label,'Visible','off');
                            set(app.model1fortheonebandmodelPanel,'Visible','off');
                            set(app.model2fortheonebandmodelPanel,'Visible','off');
                            set(app.model1forthetwobandsmodelPanel,'Visible','off');
                            set(app.model2forthetwobandsmodelPanel,'Visible','off');
                        case 'two bands'
                            set(app.customfunctionTextArea,'Visible','on');
                            set(app.customfunctionLabel,'Visible','on');
                            set(app.customfunction2TextArea,'Visible','on');
                            set(app.customfunction2Label,'Visible','on');
                            set(app.model1fortheonebandmodelPanel,'Visible','off');
                            set(app.model2fortheonebandmodelPanel,'Visible','off');
                            set(app.model1forthetwobandsmodelPanel,'Visible','off');
                            set(app.model2forthetwobandsmodelPanel,'Visible','off');
 
                    end
                case 'model 1'
                    switch selectedButton1.Text
                        case 'one band'
                            set(app.customfunctionTextArea,'Visible','off');
                            set(app.customfunctionLabel,'Visible','off');
                            set(app.customfunction2TextArea,'Visible','off');
                            set(app.customfunction2Label,'Visible','off');
                            set(app.model1fortheonebandmodelPanel,'Visible','on');
                            set(app.model2fortheonebandmodelPanel,'Visible','off');
                            set(app.model1forthetwobandsmodelPanel,'Visible','off');
                            set(app.model2forthetwobandsmodelPanel,'Visible','off');
                        case 'two bands'
                            set(app.customfunctionTextArea,'Visible','off');
                            set(app.customfunctionLabel,'Visible','off');
                            set(app.customfunction2TextArea,'Visible','off');
                            set(app.customfunction2Label,'Visible','off');
                            set(app.model1fortheonebandmodelPanel,'Visible','off');
                            set(app.model2fortheonebandmodelPanel,'Visible','off');
                            set(app.model1forthetwobandsmodelPanel,'Visible','on');
                            set(app.model2forthetwobandsmodelPanel,'Visible','off');
                    end
                case 'model 2'
                    switch selectedButton1.Text
                        case 'one band'
                            set(app.customfunctionTextArea,'Visible','off');
                            set(app.customfunctionLabel,'Visible','off');
                            set(app.customfunction2TextArea,'Visible','off');
                            set(app.customfunction2Label,'Visible','off');
                            set(app.model1fortheonebandmodelPanel,'Visible','off');
                            set(app.model2fortheonebandmodelPanel,'Visible','on');
                            set(app.model1forthetwobandsmodelPanel,'Visible','off');
                            set(app.model2forthetwobandsmodelPanel,'Visible','off');
                        case 'two bands'
                            set(app.customfunctionTextArea,'Visible','off');
                            set(app.customfunctionLabel,'Visible','off');
                            set(app.customfunction2TextArea,'Visible','off');
                            set(app.customfunction2Label,'Visible','off');
                            set(app.model1fortheonebandmodelPanel,'Visible','off');
                            set(app.model2fortheonebandmodelPanel,'Visible','off');
                            set(app.model1forthetwobandsmodelPanel,'Visible','off');
                            set(app.model2forthetwobandsmodelPanel,'Visible','on');
                    end
            end
        end

        % Selection changed function: ThegapfunctioneVButtonGroup
        function ThegapfunctioneVButtonGroupSelectionChanged(app, event)
            selectedButton = app.ThegapfunctioneVButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'custom function'
                    set(app.gapfunctionTextArea,'Visible','on');
                    set(app.gapfunctionTextAreaLabel,'Visible','on');
                    set(app.model1forthegapfunctionPanel,'Visible','off');
                    set(app.model2forthegapfunctionPanel,'Visible','off');
                case 'model 1'
                    set(app.gapfunctionTextArea,'Visible','off');
                    set(app.gapfunctionTextAreaLabel,'Visible','off');
                    set(app.model1forthegapfunctionPanel,'Visible','on');
                    set(app.model2forthegapfunctionPanel,'Visible','off');
                case 'model 2'
                    set(app.gapfunctionTextArea,'Visible','off');
                    set(app.gapfunctionTextAreaLabel,'Visible','off');
                    set(app.model1forthegapfunctionPanel,'Visible','off');
                    set(app.model2forthegapfunctionPanel,'Visible','on');
            end
        end

        % Button pushed function: checkButton
        function checkButtonPushed(app, event)
            selectedButton1 = app.ThenumberofbandButtonGroup.SelectedObject;
            selectedButton2 = app.ThewaytocalculateButtonGroup.SelectedObject;
            selectedButton3 = app.ThetightbindingmodeleVButtonGroup.SelectedObject;
            f1 = app.customfunctionTextArea.Value;
            selectedButton4 = app.ThegapfunctioneVButtonGroup.SelectedObject;
            f2 = app.gapfunctionTextArea.Value;
            uintra = str2double(app.U_intraeVTextArea.Value);
            uinter = str2double(app.U_intereVTextArea.Value);
            e = str2double(app.EeVTextArea.Value);
            nq = fix(str2double(app.n_qTextArea.Value));
            ne = fix(str2double(app.n_ETextArea.Value));
            d1 = str2double(app.deVTextArea.Value);
            switch selectedButton1.Text
                case 'one band'
                    n_q = nq;
                    E = e;
                    n_E = ne;
                    [kx,ky] = meshgrid(linspace(-pi,pi,n_q));
                    Epoints = linspace(-E,E,n_E);
                    I = eye(2);
                    U0 = uintra;
                    U = [U0,0;0,-U0];
                    d = d1;
                    
                    switch selectedButton3.Text
                        case 'custom function'
                            E_tb = eval(f1{1});
                        case 'model 1'
                            parameter1 = str2double(app.coskxTextArea_4.Value);
                            parameter2 = str2double(app.coskyTextArea.Value);
                            E_tb = parameter1*cos(kx)+parameter2*cos(ky);
                        case 'model 2'
                            parameter1 = str2double(app.coskxcoskyTextArea.Value);
                            E_tb = parameter1*cos(kx).*cos(ky);
                    end
                    
                    switch selectedButton4.Text
                        case 'custom function'
                             D = eval(f2{1});
                        case 'model 1'
                            parameter1 = str2double(app.coskxcoskyTextArea_4.Value);
                            D = parameter1*(cos(kx)-cos(ky));
                        case 'model 2'
                            parameter1 = str2double(app.sinkxisinkyTextArea.Value);
                            D = parameter1*(sin(kx)+1i*sin(ky));
                    end

                    G11 = zeros(n_q,n_q,n_E);
                    G12 = zeros(n_q,n_q,n_E);
                    G21 = zeros(n_q,n_q,n_E);
                    G22 = zeros(n_q,n_q,n_E);
                    G11r = zeros(n_q,n_q,n_E);
                    G12r = zeros(n_q,n_q,n_E);
                    G21r = zeros(n_q,n_q,n_E);
                    G22r= zeros(n_q,n_q,n_E);
                    for k=1:n_E
                    for i=1:n_q
                    for j=1:n_q
                       H = [E_tb(i,j),D(i,j);conj(D(i,j)),-E_tb(i,j)];
                       G0 = I/((Epoints(k) + 1i*d)*I - H);
                       G11(i,j,k) = G0(1,1);
                       G12(i,j,k) = G0(1,2);
                       G21(i,j,k) = G0(2,1);
                       G22(i,j,k) = G0(2,2);
                    end
                    end
                       G11r(:,:,k) = fftshift(ifft2(G11(:,:,k)))/(n_q*n_q);
                       G12r(:,:,k) = fftshift(ifft2(G12(:,:,k)))/(n_q*n_q);
                       G21r(:,:,k) = fftshift(ifft2(G21(:,:,k)))/(n_q*n_q);
                       G22r(:,:,k) = fftshift(ifft2(G22(:,:,k)))/(n_q*n_q);
                    end
                    
                    Ak = -imag(G11 + G22)/pi;
                    figure('name','the spectral density');
                    colorscale = 'gray';
                    for k=1:n_E
                        subplot(ceil(n_E/4),4,k);
                        imagesc(abs(Ak(:,:,k)));
                        colormap(colorscale);
                        axis equal
                        xticks([1 n_q]);
                        xticklabels({'-\pi','\pi'});
                        yticks([1 n_q]);
                        yticklabels({'-\pi','\pi'});
                        xlabel('kx');
                        ylabel('ky');
                        title([num2str(-E+(k-1)*2*E/(n_E-1)) 'eV']);
                    end
                    
                    figure('name','energy band');
                    colorscale = 'gray';
                    imagesc(abs(E_tb(:,:)));
                    colormap(colorscale);
                    axis equal
                    xticks([1 n_q]);
                    xticklabels({'-\pi','\pi'});
                    yticks([1 n_q]);
                    yticklabels({'-\pi','\pi'});
                    xlabel('kx');
                    ylabel('ky');
                    colorbar();
                    delta = 0.0001;
                    colorbar('Ticks',(delta),'TickLabels',{'Low density '})
                    
                    figure('name','energy gap');
                    colorscale = 'gray';
                    imagesc(abs(D(:,:)));
                    colormap(colorscale);
                    axis equal
                    xticks([1 n_q]);
                    xticklabels({'-\pi','\pi'});
                    yticks([1 n_q]);
                    yticklabels({'-\pi','\pi'});
                    xlabel('kx');
                    ylabel('ky');
                    colorbar();
                    delta = 0.0001;
                    colorbar('Ticks',(delta),'TickLabels',{'Low density '})

                case 'two bands'
                    f3 = app.customfunction2TextArea.Value;
                    f4 = app.gapfunction2TextArea.Value;
                    n_q = nq;
                    E = e;
                    n_E = ne;
                    [kx,ky] = meshgrid(linspace(-pi,pi,n_q));
                    Epoints = linspace(-E,E,n_E);
                    I = eye(4);
                    switch selectedButton3.Text
                        case 'custom function'
                            epsilon_alpha = eval(f1{1});
                            epsilon_beta = eval(f3{1});
                        case 'model 1'
                            parameter1 = str2double(app.coskxTextArea_2);
                            parameter2 = str2double(app.coskyTextArea_2);
                            parameter3 = str2double(app.TextArea);
                            parameter4 = str2double(app.coskxTextArea_3);
                            parameter5 = str2double(app.coskyTextArea_3);
                            parameter6 = str2double(app.TextArea_2);
                            epsilon_alpha = parameter1*cos(kx)+parameter2*cos(ky)+parameter3;
                            epsilon_beta = parameter4*cos(kx)+parameter5*cos(ky)+parameter6;
                        case 'medel 2'
                            parameter1 = str2double(app.coskxcoskyTextArea_2);
                            parameter2 = str2double(app.coskxcoskyTextArea_3);
                            epsilon_alpha =  parameter1*cos(kx).*cos(ky);
                            epsilon_beta = parameter2*cos(kx).*cos(ky);
                    end
                    switch selectedButton4.Text
                        case 'custom function'
                             D1 = eval(f2{1});
                             D2 = eval(f2{1});
%                         case 'model 1'
%                             parameter1 = str2double(app.coskxcoskyTextArea_4.Value);
%                             D = parameter1*(cos(kx)-cos(ky));
%                         case 'model 2'
%                             parameter1 = str2double(app.sinkxisinkyTextArea.Value);
%                             D = parameter1*(sin(kx)+1i*sin(ky));
                    end
                    U_intra = uintra;
                    U_inter = uinter;
                    U = [U_intra,0,U_inter,0;...
                        0,-U_intra,0,-U_inter;...
                        U_inter,0,U_intra,0;...
                        0,-U_inter,0,-U_intra];
                    d = d1;
                    
                    G11 = zeros(n_q,n_q,n_E);
                    G12 = zeros(n_q,n_q,n_E);
                    G13 = zeros(n_q,n_q,n_E);
                    G14 = zeros(n_q,n_q,n_E);
                    G21 = zeros(n_q,n_q,n_E);
                    G22 = zeros(n_q,n_q,n_E);
                    G23 = zeros(n_q,n_q,n_E);
                    G24 = zeros(n_q,n_q,n_E);
                    G31 = zeros(n_q,n_q,n_E);
                    G32 = zeros(n_q,n_q,n_E);
                    G33 = zeros(n_q,n_q,n_E);
                    G34 = zeros(n_q,n_q,n_E);
                    G41 = zeros(n_q,n_q,n_E);
                    G42 = zeros(n_q,n_q,n_E);
                    G43 = zeros(n_q,n_q,n_E);
                    G44 = zeros(n_q,n_q,n_E);
                    G11r = zeros(n_q,n_q,n_E);
                    G12r = zeros(n_q,n_q,n_E);
                    G13r = zeros(n_q,n_q,n_E);
                    G14r = zeros(n_q,n_q,n_E);
                    G21r = zeros(n_q,n_q,n_E);
                    G22r = zeros(n_q,n_q,n_E);
                    G23r = zeros(n_q,n_q,n_E);
                    G24r = zeros(n_q,n_q,n_E);
                    G31r = zeros(n_q,n_q,n_E);
                    G32r = zeros(n_q,n_q,n_E);
                    G33r = zeros(n_q,n_q,n_E);
                    G34r = zeros(n_q,n_q,n_E);
                    G41r = zeros(n_q,n_q,n_E);
                    G42r = zeros(n_q,n_q,n_E);
                    G43r = zeros(n_q,n_q,n_E);
                    G44r = zeros(n_q,n_q,n_E);
                    for k=1:n_E
                    for i=1:n_q
                    for j=1:n_q
                        H = [epsilon_alpha(i,j),D1(i,j),0,0;...
                            conj(D1(i,j)),-epsilon_alpha(i,j),0,0;...
                            0,0,epsilon_beta(i,j),D2(i,j);...
                            0,0,conj(D2(i,j)),-epsilon_beta(i,j)];
                        G0 = I/((Epoints(k) + 1i*d)*I - H);
                        G11(i,j,k) = G0(1,1);
                        G12(i,j,k) = G0(1,2);
                        G13(i,j,k) = G0(1,3);
                        G14(i,j,k) = G0(1,4);
                        G21(i,j,k) = G0(2,1);
                        G22(i,j,k) = G0(2,2);
                        G23(i,j,k) = G0(2,3);
                        G24(i,j,k) = G0(2,4);
                        G31(i,j,k) = G0(3,1);
                        G32(i,j,k) = G0(3,2);
                        G33(i,j,k) = G0(3,3);
                        G34(i,j,k) = G0(3,4);
                        G41(i,j,k) = G0(4,1);
                        G42(i,j,k) = G0(4,2);
                        G43(i,j,k) = G0(4,3);
                        G44(i,j,k) = G0(4,4);
                    end
                    end
                        G11r(:,:,k) = fftshift(ifft2(G11(:,:,k)))/(n_q*n_q);
                        G12r(:,:,k) = fftshift(ifft2(G12(:,:,k)))/(n_q*n_q);
                        G13r(:,:,k) = fftshift(ifft2(G13(:,:,k)))/(n_q*n_q);
                        G14r(:,:,k) = fftshift(ifft2(G14(:,:,k)))/(n_q*n_q);
                        G21r(:,:,k) = fftshift(ifft2(G21(:,:,k)))/(n_q*n_q);
                        G22r(:,:,k) = fftshift(ifft2(G22(:,:,k)))/(n_q*n_q);
                        G23r(:,:,k) = fftshift(ifft2(G23(:,:,k)))/(n_q*n_q);
                        G24r(:,:,k) = fftshift(ifft2(G24(:,:,k)))/(n_q*n_q);
                        G31r(:,:,k) = fftshift(ifft2(G31(:,:,k)))/(n_q*n_q);
                        G32r(:,:,k) = fftshift(ifft2(G32(:,:,k)))/(n_q*n_q);
                        G33r(:,:,k) = fftshift(ifft2(G33(:,:,k)))/(n_q*n_q);
                        G34r(:,:,k) = fftshift(ifft2(G34(:,:,k)))/(n_q*n_q);
                        G41r(:,:,k) = fftshift(ifft2(G41(:,:,k)))/(n_q*n_q);
                        G42r(:,:,k) = fftshift(ifft2(G42(:,:,k)))/(n_q*n_q);
                        G43r(:,:,k) = fftshift(ifft2(G43(:,:,k)))/(n_q*n_q);
                        G44r(:,:,k) = fftshift(ifft2(G44(:,:,k)))/(n_q*n_q);
                    end
                    
                    Ak = -imag(G11+G22+G33+G44)/pi;
                    figure('name','the spectral density')
                    colorscale = 'gray';
                    for k=1:n_E
                        subplot(ceil(n_E/4),4,k);
                        imagesc(abs(Ak(:,:,k)));
                        colormap(colorscale);
                        axis equal
                        xticks([1 n_q]);
                        xticklabels({'-\pi','\pi'});
                        yticks([1 n_q]);
                        yticklabels({'-\pi','\pi'});
                        xlabel('kx');
                        ylabel('ky');
                        title([num2str(-E+(k-1)*2*E/(n_E-1)) 'eV']);
                    end
                    
                    figure('name','energy band 1');
                    colorscale = 'gray';
                    imagesc(abs(epsilon_alpha(:,:)));
                    colormap(colorscale);
                    axis equal
                    xticks([1 n_q]);
                    xticklabels({'-\pi','\pi'});
                    yticks([1 n_q]);
                    yticklabels({'-\pi','\pi'});
                    xlabel('kx');
                    ylabel('ky');
                    colorbar();
                    delta = 0.0001;
                    colorbar('Ticks',(delta),'TickLabels',{'Low density '})
                    
                    figure('name','energy band 2');
                    colorscale = 'gray';
                    imagesc(abs(epsilon_beta(:,:)));
                    colormap(colorscale);
                    axis equal
                    xticks([1 n_q]);
                    xticklabels({'-\pi','\pi'});
                    yticks([1 n_q]);
                    yticklabels({'-\pi','\pi'});
                    xlabel('kx');
                    ylabel('ky');
                    colorbar();
                    delta = 0.0001;
                    colorbar('Ticks',(delta),'TickLabels',{'Low density '})

                    figure('name','energy gap1');
                    colorscale = 'gray';
                    imagesc(abs(D1(:,:)));
                    colormap(colorscale);
                    axis equal
                    xticks([1 n_q]);
                    xticklabels({'-\pi','\pi'});
                    yticks([1 n_q]);
                    yticklabels({'-\pi','\pi'});
                    xlabel('kx');
                    colorbar();
                    delta = 0.0001;
                    colorbar('Ticks',(delta),'TickLabels',{'Low density '})
                    
                    figure('name','energy gap2');
                    colorscale = 'gray';
                    imagesc(abs(D2(:,:)));
                    colormap(colorscale);
                    axis equal
                    xticks([1 n_q]);
                    xticklabels({'-\pi','\pi'});
                    yticks([1 n_q]);
                    yticklabels({'-\pi','\pi'});
                    xlabel('kx');
                    colorbar();
                    delta = 0.0001;
                    colorbar('Ticks',(delta),'TickLabels',{'Low density '})

            end         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ThegapfunctioneVButtonGroup = uibuttongroup(app.UIFigure);
            app.ThegapfunctioneVButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThegapfunctioneVButtonGroupSelectionChanged, true);
            app.ThegapfunctioneVButtonGroup.Title = 'The gap function(eV):';
            app.customfunctionButton_2 = uiradiobutton(app.ThegapfunctioneVButtonGroup);
            app.customfunctionButton_2.Text = 'custom function';
            app.customfunctionButton_2.Value = true;
            app.model1Button_2 = uiradiobutton(app.ThegapfunctioneVButtonGroup);
            app.model1Button_2.Text = 'model 1';
            app.model2Button_2 = uiradiobutton(app.ThegapfunctioneVButtonGroup);
            app.model2Button_2.Text = 'model 2';
            app.ThetightbindingmodeleVButtonGroup = uibuttongroup(app.UIFigure);
            app.ThetightbindingmodeleVButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThetightbindingmodeleVButtonGroupSelectionChanged, true);
            app.ThetightbindingmodeleVButtonGroup.Title = 'The tight binding model(eV):';
            app.customfunctionButton = uiradiobutton(app.ThetightbindingmodeleVButtonGroup);
            app.customfunctionButton.Text = 'custom function';
            app.customfunctionButton.Value = true;
            app.model1Button = uiradiobutton(app.ThetightbindingmodeleVButtonGroup);
            app.model1Button.Text = 'model 1';
            app.model2Button = uiradiobutton(app.ThetightbindingmodeleVButtonGroup);
            app.model2Button.Text = 'model 2';
            app.ThenumberofbandButtonGroup = uibuttongroup(app.UIFigure);
            app.ThenumberofbandButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThenumberofbandButtonGroupSelectionChanged, true);
            app.ThenumberofbandButtonGroup.Title = 'The number of band:';
            app.onebandButton = uiradiobutton(app.ThenumberofbandButtonGroup);
            app.onebandButton.Text = 'one band';
            app.onebandButton.Value = true;
            app.twobandsButton = uiradiobutton(app.ThenumberofbandButtonGroup);
            app.twobandsButton.Text = 'two bands';
            app.customfunctionTextArea = uitextarea(app.UIFigure);
            app.customfunctionLabel = uilabel(app.UIFigure);
            app.customfunctionLabel.Text = 'custom function:';
            app.gapfunctionTextArea = uitextarea(app.UIFigure);
            app.gapfunctionTextAreaLabel = uilabel(app.UIFigure);
            app.gapfunctionTextAreaLabel.Text = ' gap function:';
            app.U_intraeVTextArea = uitextarea(app.UIFigure);
            app.U_intraeVTextAreaLabel = uilabel(app.UIFigure);
            app.U_intraeVTextAreaLabel.Text = 'U_intra(eV)';
            app.U_intereVTextArea = uitextarea(app.UIFigure);
            app.U_intereVTextArea.Visible = 'off';
            app.U_intereVTextAreaLabel = uilabel(app.UIFigure);
            app.U_intereVTextAreaLabel.Visible = 'off';
            app.U_intereVTextAreaLabel.Text = 'U_inter(eV)';
            app.n_qTextAreaLabel = uilabel(app.UIFigure);
            app.n_qTextAreaLabel.Text = 'n_q';
            app.n_qTextArea = uitextarea(app.UIFigure);
            app.EeVTextAreaLabel = uilabel(app.UIFigure);
            app.EeVTextAreaLabel.Text = 'E(eV)';
            app.EeVTextArea = uitextarea(app.UIFigure);
            app.n_ETextAreaLabel = uilabel(app.UIFigure);
            app.n_ETextAreaLabel.Text = 'n_E';
            app.n_ETextArea = uitextarea(app.UIFigure);
            app.deVTextAreaLabel = uilabel(app.UIFigure);
            app.deVTextAreaLabel.Text = 'd(eV)';
            app.deVTextArea = uitextarea(app.UIFigure);
            app.ThewaytocalculateButtonGroup = uibuttongroup(app.UIFigure);
            app.ThewaytocalculateButtonGroup.Title = ' The way to calculate:';
            app.TmatrixButton = uiradiobutton(app.ThewaytocalculateButtonGroup);
            app.TmatrixButton.Text = 'T matrix';
            app.TmatrixButton.Value = true;
            app.JDOSButton = uiradiobutton(app.ThewaytocalculateButtonGroup);
            app.JDOSButton.Text = 'JDOS';
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate!';
            app.customfunction2Label = uilabel(app.UIFigure);
            app.customfunction2Label.Text = 'custom function2:';
            app.customfunction2TextArea = uitextarea(app.UIFigure);
            app.model1fortheonebandmodelPanel = uipanel(app.UIFigure);
            app.model1fortheonebandmodelPanel.Title = 'model 1 for the one band model';
            app.coskxTextArea_4Label = uilabel(app.model1fortheonebandmodelPanel);
            app.coskxTextArea_4Label.Text = '*  cos(kx)';
            app.coskxTextArea_4 = uitextarea(app.model1fortheonebandmodelPanel);
            app.Label = uilabel(app.model1fortheonebandmodelPanel);
            app.Label.Text = '+';
            app.coskyLabel = uilabel(app.model1fortheonebandmodelPanel);
            app.coskyLabel.Text = '* cos(ky)';
            app.coskyTextArea = uitextarea(app.model1fortheonebandmodelPanel);
            app.model2fortheonebandmodelPanel = uipanel(app.UIFigure);
            app.model2fortheonebandmodelPanel.Title = 'model 2 for the one band model';
            app.coskxcoskyLabel = uilabel(app.model2fortheonebandmodelPanel);
            app.coskxcoskyLabel.Text = '*  cos(kx) * cos(ky)';
            app.coskxcoskyTextArea = uitextarea(app.model2fortheonebandmodelPanel);
            app.model1forthetwobandsmodelPanel = uipanel(app.UIFigure);
            app.model1forthetwobandsmodelPanel.Title = 'model 1 for the two bands model';
            app.coskxTextArea_2Label = uilabel(app.model1forthetwobandsmodelPanel);
            app.coskxTextArea_2Label.Text = '*  cos(kx)';
            app.coskxTextArea_2 = uitextarea(app.model1forthetwobandsmodelPanel);
            app.Label_2 = uilabel(app.model1forthetwobandsmodelPanel);
            app.Label_2.Text = '+';
            app.coskyTextArea_2Label = uilabel(app.model1forthetwobandsmodelPanel);
            app.coskyTextArea_2Label.Text = '* cos(ky)';
            app.coskyTextArea_2 = uitextarea(app.model1forthetwobandsmodelPanel);
            app.thefirstbandLabel = uilabel(app.model1forthetwobandsmodelPanel);
            app.thefirstbandLabel.Text = 'the first band:';
            app.thesecondbandLabel = uilabel(app.model1forthetwobandsmodelPanel);
            app.thesecondbandLabel.Text = 'the second band:';
            app.Label_3 = uilabel(app.model1forthetwobandsmodelPanel);
            app.Label_3.Text = '+';
            app.TextArea = uitextarea(app.model1forthetwobandsmodelPanel);
            app.Label_4 = uilabel(app.model1forthetwobandsmodelPanel);
            app.Label_4.Text = '+';
            app.coskxTextArea_3Label = uilabel(app.model1forthetwobandsmodelPanel);
            app.coskxTextArea_3Label.Text = '*  cos(kx)';
            app.coskxTextArea_3 = uitextarea(app.model1forthetwobandsmodelPanel);
            app.coskyTextArea_3Label = uilabel(app.model1forthetwobandsmodelPanel);
            app.coskyTextArea_3Label.Text = '* cos(ky)';
            app.coskyTextArea_3 = uitextarea(app.model1forthetwobandsmodelPanel);
            app.TextArea_2Label = uilabel(app.model1forthetwobandsmodelPanel);
            app.TextArea_2Label.Text = '+';
            app.TextArea_2 = uitextarea(app.model1forthetwobandsmodelPanel);
            app.model2forthetwobandsmodelPanel = uipanel(app.UIFigure);
            app.model2forthetwobandsmodelPanel.Title = 'model 2 for the two bands model';
            app.thefirstbandLabel_2 = uilabel(app.model2forthetwobandsmodelPanel);
            app.thefirstbandLabel_2.Text = 'the first band:';
            app.thesecondbandLabel_2 = uilabel(app.model2forthetwobandsmodelPanel);
            app.thesecondbandLabel_2.Text = 'the second band:';
            app.coskxcoskyTextArea_2Label = uilabel(app.model2forthetwobandsmodelPanel);
            app.coskxcoskyTextArea_2Label.Text = '*  cos(kx) * cos(ky)';
            app.coskxcoskyTextArea_2 = uitextarea(app.model2forthetwobandsmodelPanel);
            app.coskxcoskyTextArea_3Label = uilabel(app.model2forthetwobandsmodelPanel);
            app.coskxcoskyTextArea_3Label.Text = '*  cos(kx) * cos(ky)';
            app.coskxcoskyTextArea_3 = uitextarea(app.model2forthetwobandsmodelPanel);
            app.model1forthegapfunctionPanel = uipanel(app.UIFigure);
            app.model1forthegapfunctionPanel.Title = 'model 1 for the gap function:';
            app.coskxcoskyTextArea_4Label = uilabel(app.model1forthegapfunctionPanel);
            app.coskxcoskyTextArea_4Label.Text = '*  ( cos(kx) - cos(ky) )';
            app.coskxcoskyTextArea_4 = uitextarea(app.model1forthegapfunctionPanel);
            app.model2forthegapfunctionPanel = uipanel(app.UIFigure);
            app.model2forthegapfunctionPanel.Title = 'model 2 for the gap function:';
            app.sinkxisinkyTextAreaLabel = uilabel(app.model2forthegapfunctionPanel);
            app.sinkxisinkyTextAreaLabel.Text = '*  ( sin(kx) + i * sin(ky) )';
            app.sinkxisinkyTextArea = uitextarea(app.model2forthegapfunctionPanel);
            app.checkButton = uibutton(app.UIFigure, 'push');
            app.checkButton.ButtonPushedFcn = createCallbackFcn(app, @checkButtonPushed, true);
            app.checkButton.Text = 'check!';
            app.gapfunction2TextAreaLabel = uilabel(app.UIFigure);
            app.gapfunction2TextAreaLabel.Text = ' gap function2:';
            app.gapfunction2TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end